classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Lamp_2                          matlab.ui.control.Lamp
        Lamp                            matlab.ui.control.Lamp
        EndDemonstrationButton          matlab.ui.control.Button
        RobotDropDown                   matlab.ui.control.DropDown
        RobotDropDownLabel              matlab.ui.control.Label
        Joint6Slider                    matlab.ui.control.Slider
        Joint6SliderLabel               matlab.ui.control.Label
        Joint5Slider                    matlab.ui.control.Slider
        Joint5Slider_2Label             matlab.ui.control.Label
        Joint4Slider                    matlab.ui.control.Slider
        Joint4Slider_2Label             matlab.ui.control.Label
        Joint3Slider                    matlab.ui.control.Slider
        Joint3Slider_2Label             matlab.ui.control.Label
        Joint2Slider                    matlab.ui.control.Slider
        Joint2Slider_2Label             matlab.ui.control.Label
        Joint1Slider                    matlab.ui.control.Slider
        Joint1Slider_2Label             matlab.ui.control.Label
        StartTeachButton                matlab.ui.control.Button
        StartBlackjackButton            matlab.ui.control.Button
        HitButton                       matlab.ui.control.Button
        StandButton                     matlab.ui.control.Button
        QueueofCoordinatesSentTextAreaLabel_2  matlab.ui.control.Label
        CancelCoordinateMovementButton  matlab.ui.control.Button
        SendCartesianCoordinateButton   matlab.ui.control.Button
        ZCoordinateEditField            matlab.ui.control.NumericEditField
        ZCoordinateEditFieldLabel       matlab.ui.control.Label
        YCoordinateEditField            matlab.ui.control.NumericEditField
        YCoordinateEditFieldLabel       matlab.ui.control.Label
        XCoordinateEditField            matlab.ui.control.NumericEditField
        XCoordinateEditFieldLabel       matlab.ui.control.Label
        StartContinueButton             matlab.ui.control.Button
        EmergencyStopButton             matlab.ui.control.Button
    end

    
    properties (Access = public)
        logFile; % Variable to store the log file created
        
        moveAuboCart = [0 0 0]; % Array element of x-y-z coordinates for Aubo i5 to move to
        moveDobotCart = [0 0 0]; % Array element of x-y-z coordinates for Dobot Magician to move to
        moveAuboDeg = zeros(1,6); % Array element for user to move individual joints of Aubo i5
        moveDobotDeg = zeros(1,5); % Array element for user to move individual joints of Dobot Magician
        robotToMove; % Variable that determines what robot to move
        
        simulationMode = 'none'; % Variable to determine if blackjack or teach functionality is required
        eStopPressed = false; % Variable to determine if the emergency stop has been pressed
        startPressed = false; % Variable to determine if the start/continue button has been pressed
        endDemonstration = false; % Variable to determine if user wishes to terminate the program
        blackjackAction = 'none'; % Variable to determine what action of blackjack to undergo

        auboI5; % Variable to store the auboI5 object
        dobotMagician; % Variable to store the dobot magician object
    end

    events
        % Creating events for main function to listen to
        StartButtonPressed; % Event for demo to start/continue
        EmergencyButtonPressed; % Event for demo to pause
        EndDemonstration; % Event for end demonstration
        DemonstrationModeChanged; % Event for demonstration mode change
        BlackjackButtonRequest; % Event for hit/stand functionality
        CartesianCoordSent; % Event for cartesian movement
        CartesianCoordCancelled; % Event for cartesian movement cancelation
        JointMovementSent; % Event for individual joint movement
    end

    methods (Access = public)
        function LoadLogFile(app, L)
            % Loading the log file to the app
            app.logFile = L;
        end

        function LoadRobots(app, aubo, dobot)
            % Loading the robot objects into the app parameters
            app.auboI5 = aubo;
            app.dobotMagician = dobot;

            % Updating the joint angles of the robots
            app.moveAuboDeg = rad2deg(aubo.model.getpos());
            app.moveDobotDeg = rad2deg(dobot.model.getpos());
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SendCartesianCoordinateButton
        function SendCartesianCoordinateButtonPushed(app, event)
            % Getting the x,y,z values to create a coordiante point
            newCoordinate = [app.XCoordinateEditField.Value, ...
                app.YCoordinateEditField.Value, app.ZCoordinateEditField.Value];

            % Switch case based off selected robot to manipulate
            switch app.RobotDropDown.Value
                case 'Aubo I5'
                    % Updating the cartesian goal of the Aubo i5
                    app.moveAuboCart = newCoordinate;
                    app.logFile.mlog = {app.logFile.DEBUG,'SendCartesianCoordinateButtonPushed', ...
                    'Cartesian goal sent to the Aubo i5'};

                case 'Dobot Magician'
                    % Updating the cartesian goal of the Dobot Magician
                    app.moveDobotCart = newCoordinate;
                    app.logFile.mlog = {app.logFile.DEBUG,'SendCartesianCoordinateButtonPushed', ...
                    'Cartesian goal sent to the Dobot Magician'};
            end
        end

        % Button pushed function: CancelCoordinateMovementButton
        function CancelCoordinateMovementButtonPushed(app, event)
            % Switch case based off selected robot to manipulate
            switch app.RobotDropDown.Value
                case 'Aubo I5'
                    % Clearing the cartesian goal of the Aubo i5
                    app.moveAuboCart = [0 0 0];
                    app.logFile.mlog = {app.logFile.DEBUG,'CancelCoordinateMovementButtonPushed', ...
                    'Cleared Aubo i5 cartesian goal'};

                case 'Dobot Magician'
                    % Clearing the cartesian goal of the Dobot Magician
                    app.moveDobotCart = [0 0 0];
                    app.logFile.mlog = {app.logFile.DEBUG,'CancelCoordinateMovementButtonPushed', ...
                    'Cleared Dobot Magician cartesian goal'};
            end
        end

        % Button pushed function: EmergencyStopButton
        function EmergencyStopButtonPushed(app, event)
            % Updating e-stop property to opposite of its prior state
            app.eStopPressed = ~app.eStopPressed;

            % if e-stop has been pressed on, start/continue must turn false
            if app.eStopPressed
                % Updating startPressed property of GUI
                app.startPressed = false;
                app.logFile.mlog = {app.logFile.DEBUG,'EmergencyStopButtonPushed', ...
                    'Emergency stop pressed, robot function paused'};
            else
                app.logFile.mlog = {app.logFile.DEBUG,'EmergencyStopButtonPushed', ...
                    'Emergency stop turned off, press continue button when ready'};
            end
        end

        % Button pushed function: StartContinueButton
        function StartContinueButtonPushed(app, event)
            % Checking if the e-stop is off (meaning robot can start/continue)
            if ~app.eStopPressed
                % If e-stop hasn't been pressed, the start property can
                % be turned true - meaning robot can start operation
                app.startPressed = true;
                app.logFile.mlog = {app.logFile.DEBUG,'StartContinueButtonPushed', ...
                    'Robot function started/continued'};
            else
                % Logging that e-stop is on and therefore robots cannot operate
                app.logFile.mlog = {app.logFile.DEBUG,'StartContinueButtonPushed', ...
                    'Cannot start/continue robot operation if e-stop has been pressed'};
            end
        end

        % Value changed function: RobotDropDown
        function RobotDropDownValueChanged(app, event)
            % Updating what robot to move
            app.robotToMove = app.RobotDropDown.Value;

            % Enabling/Disabling joint 6 movement depending on if
            % Aubo i5 or Dobot Magician is selected
            switch app.RobotDropDown.Value
                case 'Aubo I5'
                    % Enabling joint 6 movement
                    app.Joint6Slider.Enable = true;

                    % Updating Slider Limits
                    app.Joint1Slider.Limits = [-175, 175];
                    app.Joint2Slider.Limits = [-175, 175];
                    app.Joint3Slider.Limits = [-175, 175];
                    app.Joint4Slider.Limits = [-175, 175];
                    app.Joint5Slider.Limits = [-175, 175];
                    app.Joint6Slider.Limits = [-360, 360];

                    % Setting slider values to current aubo angles
                    currentJointAngles = rad2deg(app.auboI5.model.getpos());
                    app.Joint1Slider.Value = currentJointAngles(1);
                    app.Joint2Slider.Value = currentJointAngles(2);
                    app.Joint3Slider.Value = currentJointAngles(3);
                    app.Joint4Slider.Value = currentJointAngles(4);
                    app.Joint5Slider.Value = currentJointAngles(5);
                    app.Joint6Slider.Value = currentJointAngles(6);

                    app.logFile.mlog = {app.logFile.DEBUG,'RobotDropDownValueChanged', ...
                    'Changed to Aubo i5 control'};

                case 'Dobot Magician'
                    % Disabling joint 6 movement
                    app.Joint6Slider.Enable = false;

                    % Updating Slider Limits
                    app.Joint1Slider.Limits = [-135, 135];
                    app.Joint2Slider.Limits = [5,    80];
                    app.Joint3Slider.Limits = [-5,   85];
                    app.Joint4Slider.Limits = [-180, 180];
                    app.Joint5Slider.Limits = [-85,  85];

                    % Setting slider values to current aubo angles
                    currentJointAngles = rad2deg(app.dobotMagician.model.getpos());
                    app.Joint1Slider.Value = currentJointAngles(1);
                    app.Joint2Slider.Value = currentJointAngles(2);
                    app.Joint3Slider.Value = currentJointAngles(3);
                    app.Joint4Slider.Value = currentJointAngles(4);
                    app.Joint5Slider.Value = currentJointAngles(5);

                    app.logFile.mlog = {app.logFile.DEBUG,'RobotDropDownValueChanged', ...
                    'Changed to Dobot Magician control'};
            end
        end

        % Button pushed function: HitButton, StandButton
        function BlackjackButtonPressed(app, event)
            % Getting the source of the event (if hit or stand button was pressed)
            sourceButtonTag = event.Source.Tag;
            app.blackjackAction = sourceButtonTag; % Setting action to new event tag

            app.logFile.mlog = {app.logFile.DEBUG,'BlackjackButtonPressed', ...
                    [sourceButtonTag, ' requested']};
        end

        % Button pushed function: StartBlackjackButton, StartTeachButton
        function DemonstrationModePressed(app, event)
            % Getting the source of the event (if demo or teach button was pressed)
            sourceButtonTag = event.Source.Tag;

            % Switch case to change the lights for user indication on mode
            switch sourceButtonTag
                % Changing the right light to green and left to grey
                case "Blackjack"
                    app.Lamp_2.Color = [0.8 0.8 0.8];
                    app.Lamp.Color = [0 1 0];
                    LabAssessment2.MainFunction();
                % Changing the left light to green and right to grey
                case "Teach"
                    app.Lamp_2.Color = [0 1 0];
                    app.Lamp.Color = [0.8 0.8 0.8];
            end

            app.simulationMode = sourceButtonTag; % Setting simulation mode to new event tag
            app.logFile.mlog = {app.logFile.DEBUG,'DemonstrationModePressed', ...
                    [sourceButtonTag, ' requested']};
        end

        % Button pushed function: EndDemonstrationButton
        function EndDemonstrationButtonPushed(app, event)
            % Updating the end demonstration gui property
            app.endDemonstration = true;
            app.logFile.mlog = {app.logFile.DEBUG,'EndDemonstrationButtonPushed', ...
                    'End demonstration requested'};
        end

        % Value changing function: Joint1Slider, Joint2Slider, 
        % ...and 4 other components
        function SliderValueChanging(app, event)
            % Getting the source of the event (what slider has changed)
            sliderSource = event.Source;

            % Getting the joint index to change the correct robot joint
            jointIndex = str2double(sliderSource.Tag);

            % Switch case based off selected robot to manipulate
            switch app.RobotDropDown.Value
                case 'Aubo I5'
                    % Updating the joint angle of specified joint index
                    app.moveAuboDeg(jointIndex) = sliderSource.Value;
                    app.logFile.mlog = {app.logFile.DEBUG,'JointSliderChanging', ...
                    'Aubo i5 joint change sent'};

                case 'Dobot Magician'
                    % Updating the joint angle of specified joint index
                    app.moveDobotDeg(jointIndex) = sliderSource.Value;
                    app.logFile.mlog = {app.logFile.DEBUG,'JointSliderChanging', ...
                    'Dobot Magician joint change sent'};
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EmergencyStopButton = uibutton(app.UIFigure, 'push');
            app.EmergencyStopButton.ButtonPushedFcn = createCallbackFcn(app, @EmergencyStopButtonPushed, true);
            app.EmergencyStopButton.Text = 'Emergency Stop';
            app.StartContinueButton = uibutton(app.UIFigure, 'push');
            app.StartContinueButton.ButtonPushedFcn = createCallbackFcn(app, @StartContinueButtonPushed, true);
            app.StartContinueButton.Text = 'Start/Continue';
            app.XCoordinateEditFieldLabel = uilabel(app.UIFigure);
            app.XCoordinateEditFieldLabel.Text = 'X Coordinate';
            app.XCoordinateEditField = uieditfield(app.UIFigure, 'numeric');
            app.YCoordinateEditFieldLabel = uilabel(app.UIFigure);
            app.YCoordinateEditFieldLabel.Text = 'Y Coordinate';
            app.YCoordinateEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZCoordinateEditFieldLabel = uilabel(app.UIFigure);
            app.ZCoordinateEditFieldLabel.Text = 'Z Coordinate';
            app.ZCoordinateEditField = uieditfield(app.UIFigure, 'numeric');
            app.SendCartesianCoordinateButton = uibutton(app.UIFigure, 'push');
            app.SendCartesianCoordinateButton.ButtonPushedFcn = createCallbackFcn(app, @SendCartesianCoordinateButtonPushed, true);
            app.SendCartesianCoordinateButton.Text = 'Send Cartesian Coordinate';
            app.CancelCoordinateMovementButton = uibutton(app.UIFigure, 'push');
            app.CancelCoordinateMovementButton.ButtonPushedFcn = createCallbackFcn(app, @CancelCoordinateMovementButtonPushed, true);
            app.CancelCoordinateMovementButton.Text = 'Cancel Coordinate Movement';
            app.QueueofCoordinatesSentTextAreaLabel_2 = uilabel(app.UIFigure);
            app.QueueofCoordinatesSentTextAreaLabel_2.Text = {'Send End-Effector to '; 'Cartesian Coordinate:'};
            app.StandButton = uibutton(app.UIFigure, 'push');
            app.StandButton.ButtonPushedFcn = createCallbackFcn(app, @BlackjackButtonPressed, true);
            app.StandButton.Tag = 'Stand';
            app.StandButton.Text = 'Stand';
            app.HitButton = uibutton(app.UIFigure, 'push');
            app.HitButton.ButtonPushedFcn = createCallbackFcn(app, @BlackjackButtonPressed, true);
            app.HitButton.Tag = 'Hit';
            app.HitButton.Text = 'Hit';
            app.StartBlackjackButton = uibutton(app.UIFigure, 'push');
            app.StartBlackjackButton.ButtonPushedFcn = createCallbackFcn(app, @DemonstrationModePressed, true);
            app.StartBlackjackButton.Tag = 'Blackjack';
            app.StartBlackjackButton.Text = 'Start Blackjack';
            app.StartTeachButton = uibutton(app.UIFigure, 'push');
            app.StartTeachButton.ButtonPushedFcn = createCallbackFcn(app, @DemonstrationModePressed, true);
            app.StartTeachButton.Tag = 'Teach';
            app.StartTeachButton.Text = 'Start Teach';
            app.Joint1Slider_2Label = uilabel(app.UIFigure);
            app.Joint1Slider_2Label.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.UIFigure);
            app.Joint1Slider.Limits = [-175 175];
            app.Joint1Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Joint1Slider.Tag = '1';
            app.Joint2Slider_2Label = uilabel(app.UIFigure);
            app.Joint2Slider_2Label.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.UIFigure);
            app.Joint2Slider.Limits = [-175 175];
            app.Joint2Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Joint2Slider.Tag = '2';
            app.Joint3Slider_2Label = uilabel(app.UIFigure);
            app.Joint3Slider_2Label.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.UIFigure);
            app.Joint3Slider.Limits = [-175 175];
            app.Joint3Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Joint3Slider.Tag = '3';
            app.Joint4Slider_2Label = uilabel(app.UIFigure);
            app.Joint4Slider_2Label.Text = 'Joint 4';
            app.Joint4Slider = uislider(app.UIFigure);
            app.Joint4Slider.Limits = [-175 175];
            app.Joint4Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Joint4Slider.Tag = '4';
            app.Joint5Slider_2Label = uilabel(app.UIFigure);
            app.Joint5Slider_2Label.Text = 'Joint 5';
            app.Joint5Slider = uislider(app.UIFigure);
            app.Joint5Slider.Limits = [-175 175];
            app.Joint5Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Joint5Slider.Tag = '5';
            app.Joint6SliderLabel = uilabel(app.UIFigure);
            app.Joint6SliderLabel.Text = 'Joint 6';
            app.Joint6Slider = uislider(app.UIFigure);
            app.Joint6Slider.Limits = [-175 175];
            app.Joint6Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Joint6Slider.Tag = '6';
            app.RobotDropDownLabel = uilabel(app.UIFigure);
            app.RobotDropDownLabel.Text = 'Robot';
            app.RobotDropDown = uidropdown(app.UIFigure);
            app.RobotDropDown.Items = {'Aubo I5', 'Dobot Magician'};
            app.RobotDropDown.ValueChangedFcn = createCallbackFcn(app, @RobotDropDownValueChanged, true);
            app.RobotDropDown.Value = 'Aubo I5';
            app.EndDemonstrationButton = uibutton(app.UIFigure, 'push');
            app.EndDemonstrationButton.ButtonPushedFcn = createCallbackFcn(app, @EndDemonstrationButtonPushed, true);
            app.EndDemonstrationButton.Text = 'End Demonstration';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp_2 = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end